classdef PlateLoaderGUi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        getStatusPanel        matlab.ui.container.Panel
        Extend_6              matlab.ui.control.Button
        Label_8               matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        setTimeDelaysPanel    matlab.ui.container.Panel
        specialFunctionPanel  matlab.ui.container.Panel
        forward2back1         matlab.ui.control.Button
        movePanel             matlab.ui.container.Panel
        moveTo                matlab.ui.container.ButtonGroup
        to5                   matlab.ui.control.ToggleButton
        to4                   matlab.ui.control.ToggleButton
        to3                   matlab.ui.control.ToggleButton
        to2                   matlab.ui.control.ToggleButton
        to1                   matlab.ui.control.ToggleButton
        movePlate             matlab.ui.control.Button
        moveFrom              matlab.ui.container.ButtonGroup
        from5                 matlab.ui.control.ToggleButton
        from4                 matlab.ui.control.ToggleButton
        from3                 matlab.ui.control.ToggleButton
        from2                 matlab.ui.control.ToggleButton
        from1                 matlab.ui.control.ToggleButton
        plateLoaderPanel      matlab.ui.container.Panel
        COMPort               matlab.ui.control.NumericEditField
        COMPORTLabel          matlab.ui.control.Label
        reset                 matlab.ui.control.Button
        connect               matlab.ui.control.Button
        disconnect            matlab.ui.control.Button
        gripperPanel          matlab.ui.container.Panel
        close                 matlab.ui.control.Button
        open                  matlab.ui.control.Button
        zaxisPanel            matlab.ui.container.Panel
        retract               matlab.ui.control.Button
        extend                matlab.ui.control.Button
        xaxisPanel            matlab.ui.container.Panel
        x1                    matlab.ui.control.Button
        x5                    matlab.ui.control.Button
        x4                    matlab.ui.control.Button
        x3                    matlab.ui.control.Button
        x2                    matlab.ui.control.Button
        movementPanel         matlab.ui.container.Panel
        ImagePlate            matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        roboticsEng           matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Robot % Description
        GripperImages
        PlatePositions
        PlateStatus
        GripperStatus
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Robot = PlateLoaderSim();
            app.GripperImages = ["./gripper_open_no_plate.jpg",
                "./gripper_closed_no_plate.jpg",
                "./gripper_with_plate.jpg"];
            app.PlatePositions(1,:) = [20,55,85,25];
            app.PlatePositions(2,:) = [148,55,85,25];
            app.PlatePositions(3,:) = [276,55,85,25];
            app.PlatePositions(4,:) = [404,55,85,25];
            app.PlatePositions(5,:) = [532,55,85,25];
            app.ImagePlate.Position = app.PlatePositions(1,:);
            app.PlateStatus = 1;
        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            % Read the COM port specified by the user (e.g., 'COM3')
            portName = app.COMPort.Value; 

            try
                % Instantiates the real PlateLoader on the specified COM port
                app.Robot = PlateLoader(portName);
        
                % Verification step: Confirm app.Robot is connected
                disp("Connected to real PlateLoader on " + portName);
            catch ME
                errordlg("Failed to connect on " + portName + ": " + ME.message, "Connection Error");
            end
        end

        % Button pushed function: disconnect
        function disconnectButtonPushed(app, event)
            % Switch back to the simulator instance
            app.Robot = PlateLoaderSim();
    
            % Verification step: Confirm app.Robot is now running in simulation mode
            disp("Switched back to PlateLoaderSim\n");
        end

        % Button pushed function: x1
        function x1ButtonPushed(app, event)
            app.Robot.x(1)
            app.Image.Position=[11,160,100,60];
        end

        % Button pushed function: x2
        function x2ButtonPushed(app, event)
            app.Robot.x(2)
            app.Image.Position=[141,160,100,60];
        end

        % Button pushed function: x3
        function x3ButtonPushed(app, event)
            app.Robot.x(3)
            app.Image.Position=[271,160,100,60];
        end

        % Button pushed function: x4
        function x4ButtonPushed(app, event)
            app.Robot.x(4)
            app.Image.Position=[401,160,100,60];
        end

        % Button pushed function: x5
        function x5ButtonPushed(app, event)
            app.Robot.x(5)
            app.Image.Position=[531,160,100,60];
        end

        % Button pushed function: extend
        function extendButtonPushed(app, event)
            app.Robot.extend()
            app.Image.Position(2) = 60;
        end

        % Button pushed function: retract
        function retractButtonPushed(app, event)
            app.Robot.retract()
            app.Image.Position(2) = 160;
        end

        % Button pushed function: open
        function openButtonPushed(app, event)
            app.Robot.open()
            app.Image.ImageSource = app.GripperImages(1);
        end

        % Button pushed function: close
        function closeButtonPushed(app, event)
            app.Robot.close();
            % [xPos,~,platePresent,~] = app.Robot.getProperties;
            % if (xPos == app.PlateStatus && ...
            %         app.Robot.isGripperClosed == false)
            %     % Make the plate image visible and place it at the current gripper coordinates
            %     app.Robot.isPlatePresent = true;
            %     app.Image.ImageSource = app.GripperImages(3);
            %     app.ImagePlate.Visible = 'off';
            % else
                app.Image.ImageSource = app.GripperImages(2);
            % end

        end

        % Button pushed function: forward2back1
        function forward2back1ButtonPushed(app, event)
            app.openButtonPushed();
            pause(0.25);
            app.x1ButtonPushed();
            pause(0.25);

            app.extendButtonPushed()
            pause(0.25);
            app.closeButtonPushed()
            pause(0.25);
            app.x3ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x2ButtonPushed(2);
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x4ButtonPushed(4);
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x3ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x5ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();
        end

        % Selection changed function: moveFrom
        function moveFromSelectionChanged(app, event)
            selectedButton = app.moveFrom.SelectedObject;
            buttons = [app.from1, app.from2, app.from3, app.from4, app.from5];
            for i=1:length(buttons)
                buttons(i).BackgroundColor = [0,0,0];
            end
            selectedButton.BackgroundColor = [0.65,0.65,0.65];
        end

        % Selection changed function: moveTo
        function moveToSelectionChanged(app, event)
            selectedButton = app.moveTo.SelectedObject;
            buttons = [app.to1, app.to2, app.to3, app.to4, app.to5];
            for i=1:length(buttons)
                buttons(i).BackgroundColor = [0,0,0];
            end
            selectedButton.BackgroundColor = [0.65,0.65,0.65];
        end

        % Callback function: not associated with a component
        function moveFromButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.movementPanel = uipanel(app.UIFigure);
            app.Label = uilabel(app.movementPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.movementPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.movementPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.movementPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.movementPanel);
            app.Label_5.Text = '5';
            app.roboticsEng = uilabel(app.movementPanel);
            app.roboticsEng.Text = 'ROBOTICS ENGINEERING';
            app.Image = uiimage(app.movementPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.ImagePlate = uiimage(app.movementPanel);
            app.ImagePlate.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.xaxisPanel = uipanel(app.UIFigure);
            app.xaxisPanel.Title = 'X-AXIS';
            app.x2 = uibutton(app.xaxisPanel, 'push');
            app.x2.ButtonPushedFcn = createCallbackFcn(app, @x2ButtonPushed, true);
            app.x2.Text = '2';
            app.x3 = uibutton(app.xaxisPanel, 'push');
            app.x3.ButtonPushedFcn = createCallbackFcn(app, @x3ButtonPushed, true);
            app.x3.Text = '3';
            app.x4 = uibutton(app.xaxisPanel, 'push');
            app.x4.ButtonPushedFcn = createCallbackFcn(app, @x4ButtonPushed, true);
            app.x4.Text = '4';
            app.x5 = uibutton(app.xaxisPanel, 'push');
            app.x5.ButtonPushedFcn = createCallbackFcn(app, @x5ButtonPushed, true);
            app.x5.Text = '5';
            app.x1 = uibutton(app.xaxisPanel, 'push');
            app.x1.ButtonPushedFcn = createCallbackFcn(app, @x1ButtonPushed, true);
            app.x1.Text = '1';
            app.zaxisPanel = uipanel(app.UIFigure);
            app.zaxisPanel.Title = 'Z-AXIS';
            app.extend = uibutton(app.zaxisPanel, 'push');
            app.extend.ButtonPushedFcn = createCallbackFcn(app, @extendButtonPushed, true);
            app.extend.Text = 'EXTEND';
            app.retract = uibutton(app.zaxisPanel, 'push');
            app.retract.ButtonPushedFcn = createCallbackFcn(app, @retractButtonPushed, true);
            app.retract.Text = 'RETRACT';
            app.gripperPanel = uipanel(app.UIFigure);
            app.gripperPanel.Title = 'GRIPPER';
            app.open = uibutton(app.gripperPanel, 'push');
            app.open.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.open.Text = 'OPEN';
            app.close = uibutton(app.gripperPanel, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.close.Text = 'CLOSE';
            app.plateLoaderPanel = uipanel(app.UIFigure);
            app.plateLoaderPanel.Title = 'PLATE LOADER';
            app.disconnect = uibutton(app.plateLoaderPanel, 'push');
            app.disconnect.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnect.Text = 'DISCONNECT';
            app.connect = uibutton(app.plateLoaderPanel, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'CONNECT';
            app.reset = uibutton(app.plateLoaderPanel, 'push');
            app.reset.Text = 'RESET';
            app.COMPORTLabel = uilabel(app.plateLoaderPanel);
            app.COMPORTLabel.Text = 'COM PORT:';
            app.COMPort = uieditfield(app.plateLoaderPanel, 'numeric');
            app.movePanel = uipanel(app.UIFigure);
            app.movePanel.Title = 'MOVE';
            app.moveFrom = uibuttongroup(app.movePanel);
            app.moveFrom.SelectionChangedFcn = createCallbackFcn(app, @moveFromSelectionChanged, true);
            app.moveFrom.Title = 'FROM:';
            app.from1 = uitogglebutton(app.moveFrom);
            app.from1.Text = '1';
            app.from1.Value = true;
            app.from2 = uitogglebutton(app.moveFrom);
            app.from2.Text = '2';
            app.from3 = uitogglebutton(app.moveFrom);
            app.from3.Text = '3';
            app.from4 = uitogglebutton(app.moveFrom);
            app.from4.Text = '4';
            app.from5 = uitogglebutton(app.moveFrom);
            app.from5.Text = '5';
            app.movePlate = uibutton(app.movePanel, 'push');
            app.movePlate.Text = {'MOVE'; 'PLATE'};
            app.moveTo = uibuttongroup(app.movePanel);
            app.moveTo.SelectionChangedFcn = createCallbackFcn(app, @moveToSelectionChanged, true);
            app.moveTo.Title = 'TO:';
            app.to1 = uitogglebutton(app.moveTo);
            app.to1.Text = '1';
            app.to2 = uitogglebutton(app.moveTo);
            app.to2.Text = '2';
            app.to3 = uitogglebutton(app.moveTo);
            app.to3.Text = '3';
            app.to4 = uitogglebutton(app.moveTo);
            app.to4.Text = '4';
            app.to5 = uitogglebutton(app.moveTo);
            app.to5.Text = '5';
            app.to5.Value = true;
            app.specialFunctionPanel = uipanel(app.UIFigure);
            app.specialFunctionPanel.Title = 'SPECIAL FUNCTION';
            app.forward2back1 = uibutton(app.specialFunctionPanel, 'push');
            app.forward2back1.ButtonPushedFcn = createCallbackFcn(app, @forward2back1ButtonPushed, true);
            app.forward2back1.Text = 'FORWARD 2 BACK 1';
            app.setTimeDelaysPanel = uipanel(app.UIFigure);
            app.setTimeDelaysPanel.Title = 'SET TIME DELAYS';
            app.getStatusPanel = uipanel(app.UIFigure);
            app.getStatusPanel.Title = 'GET STATUS';
            app.Label_6 = uilabel(app.getStatusPanel);
            app.Label_7 = uilabel(app.getStatusPanel);
            app.Label_8 = uilabel(app.getStatusPanel);
            app.Extend_6 = uibutton(app.getStatusPanel, 'push');
            app.Extend_6.Text = {'GET'; 'STATUS'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end