classdef PlateLoaderGUi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        getStatusPanel             matlab.ui.container.Panel
        getStatus                  matlab.ui.control.Button
        gripstatus                 matlab.ui.control.Label
        zStatus                    matlab.ui.control.Label
        xStatus                    matlab.ui.control.Label
        setTimeDelaysPanel         matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        specialFunctionPanel       matlab.ui.container.Panel
        forward2back1              matlab.ui.control.Button
        movePanel                  matlab.ui.container.Panel
        moveTo                     matlab.ui.container.ButtonGroup
        to5                        matlab.ui.control.ToggleButton
        to4                        matlab.ui.control.ToggleButton
        to3                        matlab.ui.control.ToggleButton
        to2                        matlab.ui.control.ToggleButton
        to1                        matlab.ui.control.ToggleButton
        movePlate                  matlab.ui.control.Button
        moveFrom                   matlab.ui.container.ButtonGroup
        from5                      matlab.ui.control.ToggleButton
        from4                      matlab.ui.control.ToggleButton
        from3                      matlab.ui.control.ToggleButton
        from2                      matlab.ui.control.ToggleButton
        from1                      matlab.ui.control.ToggleButton
        plateLoaderPanel           matlab.ui.container.Panel
        PiAddress                  matlab.ui.control.EditField
        PiIPAddressEditFieldLabel  matlab.ui.control.Label
        reset                      matlab.ui.control.Button
        connect                    matlab.ui.control.Button
        disconnect                 matlab.ui.control.Button
        gripperPanel               matlab.ui.container.Panel
        close                      matlab.ui.control.Button
        open                       matlab.ui.control.Button
        zaxisPanel                 matlab.ui.container.Panel
        retract                    matlab.ui.control.Button
        extend                     matlab.ui.control.Button
        xaxisPanel                 matlab.ui.container.Panel
        x1                         matlab.ui.control.Button
        x5                         matlab.ui.control.Button
        x4                         matlab.ui.control.Button
        x3                         matlab.ui.control.Button
        x2                         matlab.ui.control.Button
        movementPanel              matlab.ui.container.Panel
        lines                      matlab.ui.control.Image
        SETPLATESLabel             matlab.ui.control.Label
        plate5                     matlab.ui.control.Button
        plate4                     matlab.ui.control.Button
        plate3                     matlab.ui.control.Button
        plate2                     matlab.ui.control.Button
        plate1                     matlab.ui.control.Button
        ImagePlate5                matlab.ui.control.Image
        ImagePlate4                matlab.ui.control.Image
        ImagePlate3                matlab.ui.control.Image
        ImagePlate2                matlab.ui.control.Image
        ImagePlate1                matlab.ui.control.Image
        go                         matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        roboticsEng                matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Robot % Description
        GripperImages
        PlatePositions
        PlateStatus
        GripperPositions
        GripperStatus
        PlateLocations
        PlateImages
        GUIPlatePresent
        LinesPositions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Robot = PlateLoaderSim();
            app.GripperImages = ["./gripper_open_no_plate.jpg",
                "./gripper_closed_no_plate.jpg",
                "./gripper_with_plate.jpg"];
            app.GripperPositions(1,:) = [11,160,100,60];
            app.GripperPositions(2,:) = [141,160,100,60];
            app.GripperPositions(3,:) = [271,160,100,60];
            app.GripperPositions(4,:) = [401,160,100,60];
            app.GripperPositions(5,:) = [531,160,100,60];

            app.PlatePositions(1,:) = [20,55,85,25];
            app.PlatePositions(2,:) = [148,55,85,25];
            app.PlatePositions(3,:) = [280,55,85,25];
            app.PlatePositions(4,:) = [412,55,85,25];
            app.PlatePositions(5,:) = [540,55,85,25];
            app.ImagePlate1.Position = app.PlatePositions(1,:);
            app.PlateStatus = 1;
            
            app.PlateImages = {app.ImagePlate1,app.ImagePlate2,app.ImagePlate3,app.ImagePlate4,app.ImagePlate5}   ;
            for i=1:length(app.PlateImages)
                app.PlateImages{i}.Visible = 'off';
                app.PlateImages{i}.Position = app.PlatePositions(i,:);
            end

            app.PlateLocations = [0,0,0,0,0];
            app.GUIPlatePresent = false;

            app.UITable.Data = [0 0 0; 0 0 0; 0 0 0; 0 0 0; 0 0 0 ];
            app.UITable.RowName = {'From 1','From 2','From 3','From 4','From 5'};

            app.LinesPositions(5,:) = [533,119,100,95];
            app.LinesPositions(4,:) = [400,119,100,95];
            app.LinesPositions(3,:) = [271,119,100,95];
            app.LinesPositions(2,:) = [141,119,100,95];
            app.LinesPositions(1,:) = [11,119,100,95];
            app.lines.Visible = 'on';
            app.lines.Position = app.LinesPositions(5,:);

        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            soundFile = fullfile(pathToMLAPP, 'nflonfox.mp3');
            [y, Fs] = audioread(soundFile);
            sound(y, Fs);
            piIP = string(app.PiAddress.Value); 
            try
                % Instantiates the real PlateLoader on the specified COM port
                app.Robot = PlateLoader(piIP);
        
                % Verification step: Confirm app.Robot is connected
                disp("Connected to real PlateLoader on " + piIP);
            catch ME
                errordlg("Failed to connect on " + piIP + ": " + ME.message, "Connection Error");
            end
            pause(0.5);
            clear sound
        end

        % Button pushed function: disconnect
        function disconnectButtonPushed(app, event)
            % Properly close the connection to the physical robot first
            % Properly close the connection to the physical robot first
            fprintf("hi")
            if ~isempty(app.Robot)
                app.Robot.shutdown();
            end
            % Switch back to the simulator instance
            app.Robot = PlateLoaderSim();
            disp("Switched back to PlateLoaderSim");
        end

        % Button pushed function: x1
        function x1ButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.x(1)
            app.Image.Position= app.GripperPositions(1,:);
        end

        % Button pushed function: x2
        function x2ButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.x(2)
            app.Image.Position=app.GripperPositions(2,:);
        end

        % Button pushed function: x3
        function x3ButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.x(3)
            app.Image.Position=app.GripperPositions(3,:);
        end

        % Button pushed function: x4
        function x4ButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.x(4)
            app.Image.Position=app.GripperPositions(4,:);
        end

        % Button pushed function: x5
        function x5ButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.x(5)
            app.Image.Position=app.GripperPositions(5,:);
        end

        % Button pushed function: extend
        function extendButtonPushed(app, event)
            [xPos, ~, ~, plate] = app.Robot.getProperties();
            if (app.PlateImages{xPos}==1 && plate)
                fprintf("ERROR, Plates in the way");
            else
                app.Robot.extend()
                app.Image.Position(2) = 60;
            end
            app.lines.Position = app.LinesPositions(xPos,:);
            app.lines.Visible = 'on';
        end

        % Button pushed function: retract
        function retractButtonPushed(app, event)
            app.lines.Visible = 'off';
            app.Robot.retract()
            app.Image.Position(2) = 160;
        end

        % Button pushed function: open
        function openButtonPushed(app, event)
            app.Robot.open()
            [xPos,~,~,plate] = app.Robot.getProperties;
            if (app.GUIPlatePresent || plate)
                app.Image.ImageSource = app.GripperImages(1);
                app.PlateLocations(xPos) = 1;
                app.PlateImages{xPos}.Visible = 'on';
                app.GUIPlatePresent = false;
            else
                app.Image.ImageSource = app.GripperImages(1);
            end

        end

        % Button pushed function: close
        function closeButtonPushed(app, event)
            [xPos,zPos,~,~] = app.Robot.getProperties;
            if (app.PlateLocations(xPos)==1 && zPos)
                app.GUIPlatePresent = true;
                app.PlateImages{xPos}.Visible = 'off';
                app.PlateLocations(xPos)=0;
                app.Image.ImageSource = app.GripperImages(3);
                app.Robot.close()
            else
                app.Robot.close()
                app.Image.ImageSource = app.GripperImages(2);
            end

        end

        % Button pushed function: forward2back1
        function forward2back1ButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            soundFile = fullfile(pathToMLAPP, 'jeopardylong.mp3');
            [y, Fs] = audioread(soundFile);
            sound(y, Fs);
            app.openButtonPushed();
            pause(0.25);
            app.x1ButtonPushed();
            pause(0.25);

            app.extendButtonPushed()
            pause(0.25);
            app.closeButtonPushed()
            pause(0.25);
            app.x3ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x2ButtonPushed(2);
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x4ButtonPushed(4);
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x3ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();

            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.closeButtonPushed();
            pause(0.25);
            app.x5ButtonPushed();
            pause(0.25);
            app.extendButtonPushed();
            pause(0.25);
            app.openButtonPushed();
            pause(0.25);
            app.retractButtonPushed();
            
        end

        % Selection changed function: moveFrom
        function moveFromSelectionChanged(app, event)
            selectedButton = app.moveFrom.SelectedObject;
            buttons = [app.from1, app.from2, app.from3, app.from4, app.from5];
            for i=1:length(buttons)
                buttons(i).BackgroundColor = [0,0,0];
            end
            selectedButton.BackgroundColor = [0.65,0.65,0.65];
        end

        % Selection changed function: moveTo
        function moveToSelectionChanged(app, event)
            selectedButton = app.moveTo.SelectedObject;
            buttons = [app.to1, app.to2, app.to3, app.to4, app.to5];
            for i=1:length(buttons)
                buttons(i).BackgroundColor = [0,0,0];
            end
            selectedButton.BackgroundColor = [0.65,0.65,0.65];
        end

        % Callback function
        function moveFromButtonDown(app, event)

        end

        % Button pushed function: movePlate
        function movePlateButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            soundFile = fullfile(pathToMLAPP, 'wheezy-outta-here.mp3');
            [y, Fs] = audioread(soundFile);
            sound(y, Fs);
            moveFromNum = str2double(app.moveFrom.SelectedObject.Text);
            moveToNum = str2double(app.moveTo.SelectedObject.Text);
            app.Robot.movePlate(moveFromNum, moveToNum)
            app.lines.Visible = 'off';
            app.Image.Position(2) = 160;
            pause(0.5);
            app.Image.ImageSource = app.GripperImages(1);
            pause(0.5);
            app.Image.Position= app.GripperPositions(moveFromNum,:);
            pause(0.5);
            app.Image.Position(2) = 60;
            app.lines.Position = app.LinesPositions(moveFromNum,:);
            app.lines.Visible = 'on';
            pause(0.5);
            if (app.PlateLocations(moveFromNum) == 1)
                app.Image.ImageSource = app.GripperImages(3);
                app.PlateLocations(moveFromNum) = 0;
                app.PlateImages{moveFromNum}.Visible = 'off';
                app.GUIPlatePresent = true;
                pause(0.5);
                app.lines.Visible = 'off';
                app.Image.Position(2) = 160;
                pause(0.5);
                app.Image.Position= app.GripperPositions(moveToNum,:);
                pause(0.5);
                app.Image.Position(2) = 60;
                app.lines.Position = app.LinesPositions(moveToNum,:);
                app.lines.Visible = 'on';
            else
                return;
            end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            app.Robot.reset();
            app.Image.Position(2) = 160;
            app.Image.ImageSource = app.GripperImages(2);
            pause(0.5)
            app.Image.Position= app.GripperPositions(5,:);
            pause(0.5)
            app.Image.Position= app.GripperPositions(3,:);


        end

        % Button pushed function: getStatus
        function getStatusButtonPushed(app, event)
            app.Robot.getStatus();
            [xPos, zAxis, grip, plate] = app.Robot.getProperties();

            % 1. Display X-Axis Position in Label_6
            app.xStatus.Text = sprintf("Position %d", xPos);
            if zAxis
                app.zStatus.Text = "EXTENDED";
            else
                app.zStatus.Text = "RETRACTED";
            end
            if grip 
                if (plate || app.GUIPlatePresent)
                    app.gripstatus.Text = "CLOSED, PLATE";
                else
                    app.gripstatus.Text = "CLOSED, NO PLATE";
                end
            else
                app.gripstatus.Text = "OPEN";
            end
        end

        % Callback function
        function setPlatesSelectionChanged(app, event)

        end

        % Button pushed function: plate1
        function plate1ButtonPushed(app, event)
            if (isequal(app.plate1.BackgroundColor,[0,0,0]))
                app.PlateLocations(1)=1;
                app.plate1.BackgroundColor = [0.65,0.65,0.65];
            else
                app.PlateLocations(1)=0;
                app.plate1.BackgroundColor = [0,0,0];
            end
        end

        % Button pushed function: plate2
        function plate2ButtonPushed(app, event)
            if (isequal(app.plate2.BackgroundColor,[0,0,0]))
                app.PlateLocations(2)=1;
                app.plate2.BackgroundColor = [0.65,0.65,0.65];
            else
                app.PlateLocations(2)=0;
                app.plate2.BackgroundColor = [0,0,0];
            end
        end

        % Button pushed function: plate3
        function plate3ButtonPushed(app, event)
            if (isequal(app.plate3.BackgroundColor,[0,0,0]))
                app.PlateLocations(3)=1;
                app.plate3.BackgroundColor = [0.65,0.65,0.65];
            else
                app.PlateLocations(3)=0;
                app.plate3.BackgroundColor = [0,0,0];
            end
        end

        % Button pushed function: plate4
        function plate4ButtonPushed(app, event)
            if (isequal(app.plate4.BackgroundColor,[0,0,0]))
                app.PlateLocations(4)=1;
                app.plate4.BackgroundColor = [0.65,0.65,0.65];
            else
                app.PlateLocations(4)=0;
                app.plate4.BackgroundColor = [0,0,0];
            end
        end

        % Button pushed function: plate5
        function plate5ButtonPushed(app, event)
            if (isequal(app.plate5.BackgroundColor,[0,0,0]))
                app.PlateLocations(5)=1;
                app.plate5.BackgroundColor = [0.65,0.65,0.65];
            else
                app.PlateLocations(5)=0;
                app.plate5.BackgroundColor = [0,0,0];
            end
        end

        % Button pushed function: go
        function goButtonPushed(app, event)
            for i=1:length(app.PlateLocations)
                if (app.PlateLocations(i)==1)
                    app.PlateImages{i}.Visible='on';
                else
                    app.PlateImages{i}.Visible='off';
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Your table only has 3 columns: To 2, To 3, To 4
            partial = app.UITable.Data;   % 5x3
            % Reconstruct the full 5x5 matrix setTimeValues expects
            delayMatrix = zeros(5,5);
            delayMatrix(:, 2:4) = partial;
            app.Robot.setTimeValues(delayMatrix);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.movementPanel = uipanel(app.UIFigure);
            app.Label = uilabel(app.movementPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.movementPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.movementPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.movementPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.movementPanel);
            app.Label_5.Text = '5';
            app.roboticsEng = uilabel(app.movementPanel);
            app.roboticsEng.Text = '    ROBOTICS ENGINEERING';
            app.Image = uiimage(app.movementPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.go = uibutton(app.movementPanel, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.go.Text = 'GO';
            app.ImagePlate1 = uiimage(app.movementPanel);
            app.ImagePlate1.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImagePlate2 = uiimage(app.movementPanel);
            app.ImagePlate2.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImagePlate3 = uiimage(app.movementPanel);
            app.ImagePlate3.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImagePlate4 = uiimage(app.movementPanel);
            app.ImagePlate4.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImagePlate5 = uiimage(app.movementPanel);
            app.ImagePlate5.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.plate1 = uibutton(app.movementPanel, 'push');
            app.plate1.ButtonPushedFcn = createCallbackFcn(app, @plate1ButtonPushed, true);
            app.plate1.Text = '1';
            app.plate2 = uibutton(app.movementPanel, 'push');
            app.plate2.ButtonPushedFcn = createCallbackFcn(app, @plate2ButtonPushed, true);
            app.plate2.Text = '2';
            app.plate3 = uibutton(app.movementPanel, 'push');
            app.plate3.ButtonPushedFcn = createCallbackFcn(app, @plate3ButtonPushed, true);
            app.plate3.Text = '3';
            app.plate4 = uibutton(app.movementPanel, 'push');
            app.plate4.ButtonPushedFcn = createCallbackFcn(app, @plate4ButtonPushed, true);
            app.plate4.Text = '4';
            app.plate5 = uibutton(app.movementPanel, 'push');
            app.plate5.ButtonPushedFcn = createCallbackFcn(app, @plate5ButtonPushed, true);
            app.plate5.Text = '5';
            app.SETPLATESLabel = uilabel(app.movementPanel);
            app.SETPLATESLabel.Text = 'SET PLATES:';
            app.lines = uiimage(app.movementPanel);
            app.lines.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.xaxisPanel = uipanel(app.UIFigure);
            app.xaxisPanel.Title = 'X-AXIS';
            app.x2 = uibutton(app.xaxisPanel, 'push');
            app.x2.ButtonPushedFcn = createCallbackFcn(app, @x2ButtonPushed, true);
            app.x2.Text = '2';
            app.x3 = uibutton(app.xaxisPanel, 'push');
            app.x3.ButtonPushedFcn = createCallbackFcn(app, @x3ButtonPushed, true);
            app.x3.Text = '3';
            app.x4 = uibutton(app.xaxisPanel, 'push');
            app.x4.ButtonPushedFcn = createCallbackFcn(app, @x4ButtonPushed, true);
            app.x4.Text = '4';
            app.x5 = uibutton(app.xaxisPanel, 'push');
            app.x5.ButtonPushedFcn = createCallbackFcn(app, @x5ButtonPushed, true);
            app.x5.Text = '5';
            app.x1 = uibutton(app.xaxisPanel, 'push');
            app.x1.ButtonPushedFcn = createCallbackFcn(app, @x1ButtonPushed, true);
            app.x1.Text = '1';
            app.zaxisPanel = uipanel(app.UIFigure);
            app.zaxisPanel.Title = 'Z-AXIS';
            app.extend = uibutton(app.zaxisPanel, 'push');
            app.extend.ButtonPushedFcn = createCallbackFcn(app, @extendButtonPushed, true);
            app.extend.Text = 'EXTEND';
            app.retract = uibutton(app.zaxisPanel, 'push');
            app.retract.ButtonPushedFcn = createCallbackFcn(app, @retractButtonPushed, true);
            app.retract.Text = 'RETRACT';
            app.gripperPanel = uipanel(app.UIFigure);
            app.gripperPanel.Title = 'GRIPPER';
            app.open = uibutton(app.gripperPanel, 'push');
            app.open.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.open.Text = 'OPEN';
            app.close = uibutton(app.gripperPanel, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.close.Text = 'CLOSE';
            app.plateLoaderPanel = uipanel(app.UIFigure);
            app.plateLoaderPanel.Title = 'PLATE LOADER';
            app.disconnect = uibutton(app.plateLoaderPanel, 'push');
            app.disconnect.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnect.Text = 'DISCONNECT';
            app.connect = uibutton(app.plateLoaderPanel, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'CONNECT';
            app.reset = uibutton(app.plateLoaderPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'RESET';
            app.PiIPAddressEditFieldLabel = uilabel(app.plateLoaderPanel);
            app.PiIPAddressEditFieldLabel.Text = 'Pi IP Address';
            app.PiAddress = uieditfield(app.plateLoaderPanel, 'text');
            app.PiAddress.Value = 'joeyjack-pi400';
            app.movePanel = uipanel(app.UIFigure);
            app.movePanel.Title = 'MOVE';
            app.moveFrom = uibuttongroup(app.movePanel);
            app.moveFrom.SelectionChangedFcn = createCallbackFcn(app, @moveFromSelectionChanged, true);
            app.moveFrom.Title = 'FROM:';
            app.from1 = uitogglebutton(app.moveFrom);
            app.from1.Text = '1';
            app.from1.Value = true;
            app.from2 = uitogglebutton(app.moveFrom);
            app.from2.Text = '2';
            app.from3 = uitogglebutton(app.moveFrom);
            app.from3.Text = '3';
            app.from4 = uitogglebutton(app.moveFrom);
            app.from4.Text = '4';
            app.from5 = uitogglebutton(app.moveFrom);
            app.from5.Text = '5';
            app.movePlate = uibutton(app.movePanel, 'push');
            app.movePlate.ButtonPushedFcn = createCallbackFcn(app, @movePlateButtonPushed, true);
            app.movePlate.Text = {'MOVE'; 'PLATE'};
            app.moveTo = uibuttongroup(app.movePanel);
            app.moveTo.SelectionChangedFcn = createCallbackFcn(app, @moveToSelectionChanged, true);
            app.moveTo.Title = 'TO:';
            app.to1 = uitogglebutton(app.moveTo);
            app.to1.Text = '1';
            app.to2 = uitogglebutton(app.moveTo);
            app.to2.Text = '2';
            app.to3 = uitogglebutton(app.moveTo);
            app.to3.Text = '3';
            app.to4 = uitogglebutton(app.moveTo);
            app.to4.Text = '4';
            app.to5 = uitogglebutton(app.moveTo);
            app.to5.Text = '5';
            app.to5.Value = true;
            app.specialFunctionPanel = uipanel(app.UIFigure);
            app.specialFunctionPanel.Title = 'SPECIAL FUNCTION';
            app.forward2back1 = uibutton(app.specialFunctionPanel, 'push');
            app.forward2back1.ButtonPushedFcn = createCallbackFcn(app, @forward2back1ButtonPushed, true);
            app.forward2back1.Text = 'FORWARD 2 BACK 1';
            app.setTimeDelaysPanel = uipanel(app.UIFigure);
            app.setTimeDelaysPanel.Title = 'SET TIME DELAYS';
            app.UITable = uitable(app.setTimeDelaysPanel);
            app.UITable.ColumnName = {'2'; '3'; '4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.getStatusPanel = uipanel(app.UIFigure);
            app.getStatusPanel.Title = 'GET STATUS';
            app.xStatus = uilabel(app.getStatusPanel);
            app.xStatus.Text = '';
            app.zStatus = uilabel(app.getStatusPanel);
            app.zStatus.Text = '';
            app.gripstatus = uilabel(app.getStatusPanel);
            app.gripstatus.Text = '';
            app.getStatus = uibutton(app.getStatusPanel, 'push');
            app.getStatus.ButtonPushedFcn = createCallbackFcn(app, @getStatusButtonPushed, true);
            app.getStatus.Text = {'GET'; 'STATUS'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end